classdef Biseccion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SoliButton                     matlab.ui.control.Button
        IngreseelintervaloinferiorEditField  matlab.ui.control.NumericEditField
        IngreseelintervaloinferiorEditFieldLabel  matlab.ui.control.Label
        CalcularButton                 matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        IngreselafuncionEditField      matlab.ui.control.EditField
        IngreselafuncinEditFieldLabel  matlab.ui.control.Label
        IngreseelporcentajedeerrornormalizadodeseadoEditField  matlab.ui.control.NumericEditField
        IngreseelporcentajedeerrornormalizadodeseadoEditFieldLabel  matlab.ui.control.Label
        IngreseelintervalosuperiorEditField  matlab.ui.control.NumericEditField
        IngreseelintervalosuperiorEditFieldLabel  matlab.ui.control.Label
        BiseccinLabel                  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function validacion = validarCampos(app)
           if app.IngreseelporcentajedeerrornormalizadodeseadoEditField.Value ~= 0
                if ~isempty(app.IngreselafuncionEditField.Value)
                f = str2func(['@(x)' app.IngreselafuncionEditField.Value]);
                try
                disp(f(3))
                validacion=true;
                catch
                   warndlg("La funcion ingresada no es coherente o no esta en terminos de X","Advertencia")
                   validacion=false;
                end
                else
                warndlg("Por favor ingrese la funcion","Advertencia")
                validacion=false;
                end
            else
              warndlg("El error normalizado deseado no puede ser 0","Advertencia")
              validacion=false;
            end 
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            if validarCampos(app) == true
                  xai=app.IngreseelintervaloinferiorEditField.Value;
                  xbi=app.IngreseelintervalosuperiorEditField.Value;
                  tol=app.IngreseelporcentajedeerrornormalizadodeseadoEditField.Value;
                  
                  f = str2func(['@(x)' app.IngreselafuncionEditField.Value]);
                  i=1;
 
                  f1=f(xai);
                  f2=f(xbi);
 
                  ea(i)=100;
                  if f1*f2 < 0
                     xa(i)=xai; f1=f(xa(i));
                     xb(i)=xbi; f2=f(xb(i));
                     xr(i)=(xa(i)+xb(i))/2; f3=f(xr(i));


                     nr={i sprintf('%.8f',xa(i)) sprintf('%.8f',xr(i)) sprintf('%.8f',xb(i)) sprintf('%.8f',f1) sprintf('%.8f',f3) sprintf('%.8f',f2) '-'};
                     app.UITable.Data ={'','','','','','','',''};
                     app.UITable.Data(end,:)=nr;

                     while abs(ea(i)) >= tol
                          if f1*f3<0
                             xa(i+1)=xa(i);f1=f(xa(i+1));
                             xb(i+1)=xr(i);f2=f(xb(i+1));
                          end
                          if f1*f3> 0
                             xa(i+1)=xr(i);f1=f(xa(i+1));
                             xb(i+1)=xb(i);f2=f(xb(i+1));
                          end      
                          xr(i+1)=(xa(i+1)+xb(i+1))/2; f3=f(xr(i+1));
                          ea(i+1)=abs((xr(i+1)-xr(i))/(xr(i+1))*100);
                          nr={i+1 sprintf('%.8f',xa(i+1)) sprintf('%.8f',xr(i+1)) sprintf('%.8f',xb(i+1)) sprintf('%.8f',f1) sprintf('%.8f',f3) sprintf('%.8f',f2) sprintf('%.8f',ea(i+1))};
                          app.UITable.Data(end+1,:)=nr;
                          i=i+1;

                     end  
                  else
                     warndlg("No existe una raiz en ese intervalo","Advertencia");
                  end
            end
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            
        end

        % Button pushed function: SoliButton
        function SoliButtonPushed(app, event)
            delete(app)
            Ventana2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BiseccinLabel = uilabel(app.UIFigure);
            app.BiseccinLabel.Text = 'Bisección';
            app.IngreseelintervalosuperiorEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelintervalosuperiorEditFieldLabel.Text = 'Ingrese el intervalo superior:';
            app.IngreseelintervalosuperiorEditField = uieditfield(app.UIFigure, 'numeric');
            app.IngreseelporcentajedeerrornormalizadodeseadoEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelporcentajedeerrornormalizadodeseadoEditFieldLabel.Text = 'Ingrese el porcentaje de error normalizado deseado:';
            app.IngreseelporcentajedeerrornormalizadodeseadoEditField = uieditfield(app.UIFigure, 'numeric');
            app.IngreselafuncinEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafuncinEditFieldLabel.Text = 'Ingrese la función:';
            app.IngreselafuncionEditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iteración'; 'Xi'; 'Xr'; 'Xs'; 'f(Xi)'; 'f(Xr)'; 'f(Xs)'; 'Error'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.IngreseelintervaloinferiorEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelintervaloinferiorEditFieldLabel.Text = 'Ingrese el intervalo inferior:';
            app.IngreseelintervaloinferiorEditField = uieditfield(app.UIFigure, 'numeric');
            app.SoliButton = uibutton(app.UIFigure, 'push');
            app.SoliButton.ButtonPushedFcn = createCallbackFcn(app, @SoliButtonPushed, true);
            app.SoliButton.Text = 'Volver';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Biseccion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end